classdef Pass_Fail_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainPassFailApplicationTab      matlab.ui.container.Tab
        MachineLearningSetupPanel       matlab.ui.container.Panel
        ModelDropDownLabel              matlab.ui.control.Label
        MLModelDropDown                 matlab.ui.control.DropDown
        TrainedLabel                    matlab.ui.control.Label
        TypeLabel                       matlab.ui.control.Label
        AccuracyLabel                   matlab.ui.control.Label
        TypeLabel_2                     matlab.ui.control.Label
        AccuracyLabel_2                 matlab.ui.control.Label
        TrainedLabel_2                  matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        PredictedBladeCondition         matlab.ui.control.Lamp
        BladeConditionLabel             matlab.ui.control.Label
        PredictedClass                  matlab.ui.control.Label
        ProbabilityBladeCondition       matlab.ui.control.Label
        TestSetupPanel                  matlab.ui.container.Panel
        TestTypeButtonGroup             matlab.ui.container.ButtonGroup
        LiveButton                      matlab.ui.control.RadioButton
        SimulatedButton                 matlab.ui.control.RadioButton
        BrowseButtonFile                matlab.ui.control.Button
        TheSimulatedTestFileLabel       matlab.ui.control.Label
        SelectedTestFileLabel           matlab.ui.control.Label
        StartButtonMLTest               matlab.ui.control.Button
        PlotOptionsPanel                matlab.ui.container.Panel
        HoldOnPlotsSwitchLabel          matlab.ui.control.Label
        HoldOnPlotsSwitch               matlab.ui.control.Switch
        FrequencyMaxkHzSpinnerLabel     matlab.ui.control.Label
        FrequencyMaxkHzSpinner          matlab.ui.control.Spinner
        StopButtonMLTest                matlab.ui.control.Button
        ProgressMLTest                  matlab.ui.control.Button
        UIAxesRaw_Main                  matlab.ui.control.UIAxes
        UIAxesFFTAmp                    matlab.ui.control.UIAxes
        CollectMoreTrainingDataTab      matlab.ui.container.Tab
        TestSetupPanel_2                matlab.ui.container.Panel
        BrowseButtonFolderTestData      matlab.ui.control.Button
        TheBladesTopLevelFolderPathLabel_3  matlab.ui.control.Label
        SelectedFolderPathLabel         matlab.ui.control.Label
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        NumberofTestsSpinnerLabel       matlab.ui.control.Label
        NumberofTestsSpinner            matlab.ui.control.Spinner
        StartButtonTestData             matlab.ui.control.Button
        StopButtonTestData              matlab.ui.control.Button
        ProgressTestData                matlab.ui.control.Button
        UIAxesRaw_Col                   matlab.ui.control.UIAxes
        ManualFeatureExtractionTab      matlab.ui.container.Tab
        StartButtonFeatExtract          matlab.ui.control.Button
        StopButtonFeatExtract           matlab.ui.control.Button
        NextFileButtonFeatExtract       matlab.ui.control.Button
        RandomFileLabelFeatExtract      matlab.ui.control.Label
        SelectedFeatExtract             matlab.ui.control.Label
        DeleteRowButtonFeatExtract      matlab.ui.control.Button
        ManualFeatureExtractionSetupPanel  matlab.ui.container.Panel
        BrowseButtonFolderReML          matlab.ui.control.Button
        TheBladesTopLevelFolderPathLabel  matlab.ui.control.Label
        SelectedFolderPathLabelFeatExtract  matlab.ui.control.Label
        ExtractedfeaturesmatchthemachinelearningalgorithmLabel  matlab.ui.control.Label
        FrequencyMaxkHzSpinner_2Label   matlab.ui.control.Label
        FrequencyMaxkHzSpinnerFeatExtract  matlab.ui.control.Spinner
        FrequencyMinkHzLabel            matlab.ui.control.Label
        FrequencyMinkHzSpinnerFeatExtract  matlab.ui.control.Spinner
        UITablesFrequencyModes          matlab.ui.control.Table
        RownumberEditFieldLabel         matlab.ui.control.Label
        RowNumberEditFieldFeatExtract   matlab.ui.control.NumericEditField
        ConditionLabelFeatExtract       matlab.ui.control.Label
        BladeConditionSwitchFeatExtract  matlab.ui.control.Switch
        UIAxesFeatExtract               matlab.ui.control.UIAxes
        RetrainMachineLearningModelTab  matlab.ui.container.Tab
        RetrainMachineLearningModelSetupPanel  matlab.ui.container.Panel
        BrowseButtonFolderReTrainML     matlab.ui.control.Button
        TheBladesTopLevelFolderPathLabel_2  matlab.ui.control.Label
        SelectedFolderPathLabelReTrainML  matlab.ui.control.Label
        TrainingDataAvailableButtonGroup  matlab.ui.container.ButtonGroup
        UndamagedandDamagedButton       matlab.ui.control.RadioButton
        UndamagedButton                 matlab.ui.control.RadioButton
        NumberOfFeaturesText            matlab.ui.control.Label
        NumberOfFeatures                matlab.ui.control.Label
        MachineLearningMethodtoTrainListBoxLabel  matlab.ui.control.Label
        MachineLearningMethodtoTrainListBox  matlab.ui.control.ListBox
        MLmodelTable                    matlab.ui.control.Table
        ConfusionChartPanel             matlab.ui.container.Panel
        StartButtonReTrainML            matlab.ui.control.Button
        CurrentTrainedModelsLabel_2     matlab.ui.control.Label
        AccuracyofthemodelLabel         matlab.ui.control.Label
        AccuracyOfTheModel              matlab.ui.control.Label
        LossofthemodelLabel             matlab.ui.control.Label
        LossOfTheModel                  matlab.ui.control.Label
        CurrentTrainedModelsLabel_3     matlab.ui.control.Label
        CurrentTrainedModelsLabel_4     matlab.ui.control.Label
        AutoReduceFeaturesButtonGroup   matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        Descp                           matlab.ui.control.Label
        CurrentTrainedModelsLabel_5     matlab.ui.control.Label
        DeleteRowButtonReTrainML        matlab.ui.control.Button
        ModeltoDeleteDropDownLabel      matlab.ui.control.Label
        ModeltoDeleteDropDown           matlab.ui.control.DropDown
        StatusLabel                     matlab.ui.control.Label
        ErrorLamp                       matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        applicationPath % The path the application is in
        MLmodel % The ML mdl and params used to train it and to be used in the test
        featFileCreateDate % Date feature extraction occurred
        MLFileCreateDate % Date ML was trained
        CSVFilePath % The file path and name of a CSV file
        trainingDataFolderPath % The folder path for the training data
        prevFreqPeaks % The saved frequency peaks from the .mat file
        stopPressed % 0 or 1 if a stop button has been pressed
    end
    
    methods (Access = private)
     
        
        function CheckRequiredToolbox(app)
            % This function checks if all the required toolboxes are installed
            requiredToolBoxes = {'Parallel Computing Toolbox',...
                'Signal Processing Toolbox',...
                'Statistics and Machine Learning Toolbox',...
                'Instrument Control Toolbox',...
                'PicoScope Support Toolbox'};
            v_=ver;
            [installedToolboxes{1:length(v_)}] = deal(v_.Name);
            for i = 1:length(requiredToolBoxes)
                result = all(ismember(requiredToolBoxes(i),installedToolboxes));
                if result == 0
                    f = uifigure;
                    msg = ['You have a missing toolbox which must be ',...
                        'installed to run the application. ',...
                        'Missing Toolbox: ',requiredToolBoxes(i)];
                    title = 'Missing Toolbox';
                    uiconfirm(f,msg,title,...
                       'Options',{'Okay'},...
                       'DefaultOption',1,'CancelOption',1,...
                       'CloseFcn',@(h,e) close(f));
                end
            end
        end
        
        function PopulateMLDropDownOptions(app)
            % This function updates the ML drop down option box and desc
            try
                models = dir(fullfile(app.applicationPath,'model*.mat'));
                for i = 1:numel(models)
                    modelFile = models(i);
                    modelFileName = extractBefore(modelFile.name,'.');
                    modelName{i} = extractAfter(modelFileName,'-');
                end
                app.MLModelDropDown.Items = modelName;
                app.ModeltoDeleteDropDown.Items = modelName;
            
                MLModelDropDownValueChanged(app,[]);
            catch
                UpdateStatus(app,0,"No trained models")
            end
        end
        
        function UpdateMLTestPlots(app,dataOut,holdOn)
            % Function updates the plots with the new output data
            if holdOn == "Yes"
                hold(app.UIAxesRaw_Main,"on")
                hold(app.UIAxesFFTAmp,"on")
            end
            
            plot(app.UIAxesRaw_Main,dataOut.rawSigTime,dataOut.rawSig)
            xlim(app.UIAxesRaw_Main,[0 inf])
            
            plot(app.UIAxesFFTAmp,dataOut.f/1000,dataOut.amp)
            hold(app.UIAxesFFTAmp,"on")
            if app.MLmodel.MLmodelType ~= "DL"
            plot(app.UIAxesFFTAmp,dataOut.extractedFreqs/1000,...
                dataOut.ampAtExtractedFreqs,'r^','markerfacecolor',[1 0 0])
            end
            hold(app.UIAxesFFTAmp,"off")
            xlim(app.UIAxesFFTAmp,[0 app.FrequencyMaxkHzSpinner.Value])          
        end
        
        function ResetMLTestPlots(app)
            % Function clears the plots
            hold(app.UIAxesRaw_Main,"off")
            hold(app.UIAxesFFTAmp,"off")
            xlim(app.UIAxesRaw_Main,[0 inf])
            xlim(app.UIAxesFFTAmp,[0 inf])
            plot(app.UIAxesRaw_Main,0,0);
            plot(app.UIAxesFFTAmp,0,0);
        end

        function PopulateFrequencyModesTable(app)
            % Function populates the table of mode frequencies
            numNodes = length(app.prevFreqPeaks.freqPeaks);
            if numNodes ~= 0
                app.UITablesFrequencyModes.Data = [string(1:numNodes)',...
                    string(app.prevFreqPeaks.freqPeaks)'];
            else
                app.UITablesFrequencyModes.Data = [-1,-1];
            end
        end
        
        function UpdateFeatExtractPlots(app)
            % Function plots the file and peaks from .mat file
            data = readtable(app.CSVFilePath);
            dataOut = PreprocessData(data);
            plot(app.UIAxesFeatExtract,dataOut.f/1000,dataOut.amp)
            xlim(app.UIAxesFeatExtract,...
                [app.FrequencyMinkHzSpinnerFeatExtract.Value app.FrequencyMaxkHzSpinnerFeatExtract.Value])  
            
            freqPeaks = app.prevFreqPeaks.freqPeaks;
            for i = 1:length(freqPeaks)
                if ~isnan(freqPeaks(i))
                    xline(app.UIAxesFeatExtract,freqPeaks(i),'--r');
                end
            end
        end
        
        function PopulateReTrainMLTable(app)
            % This function populates the table of previous ML models
            try
                models = dir(fullfile(app.applicationPath,'model*.mat'));
                for i = 1:numel(models)
                    modelFile = models(i);
                    modelFileName = extractBefore(modelFile.name,'.');
                    modelName = extractAfter(modelFileName,'-');
                    modelFileTrained = extractBefore(modelFile.date,' ');
                    modelInfo = load(modelFileName);
    
                    modelType = modelInfo.trainingData;
                    modelAcc = modelInfo.mdlAcc;
                    
                    data(i,:) = [modelName,modelFileTrained,modelType,string(modelAcc)];  
                end
                set(app.MLmodelTable,'Data',data,...
                    'ColumnFormat',{'char','char','char','char'})
            catch 
                UpdateStatus(app,0,"No trained models")
            end
        end
                
        function UpdateStatus(app,error,message)
            % This function updates the status bar for the progress of the
            % test and of any errors
            app.StatusLabel.Text = message;
            if error == 1
                app.ErrorLamp.Color = 'red';
            else
                app.ErrorLamp.Color = 'green';
            end
        end  
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Functions to run on start-up of the application
            set(app.StopButtonMLTest,'Enable','off');
            set(app.StartButtonTestData,'Enable','off');
            set(app.StopButtonTestData,'Enable','off');
            set(app.StartButtonFeatExtract,'Enable','off');
            set(app.StopButtonFeatExtract,'Enable','off');
            set(app.NextFileButtonFeatExtract,'Enable','off');
            set(app.BladeConditionSwitchFeatExtract,'Enable','off');
            set(app.DeleteRowButtonFeatExtract,'Enable','off');
            set(app.StartButtonReTrainML,'Enable','off');
            
            app.stopPressed = 0;
            app.applicationPath = fileparts(mfilename('fullpath'));
            
            try
                app.prevFreqPeaks = load(fullfile(app.applicationPath,...
                    'freqPeaksLoc'));
                file = dir(fullfile(app.applicationPath,'freqPeaksLoc.mat'));
                app.featFileCreateDate = extractBefore(file.date,' ');
            catch
                % No .mat file has been created
                errordlg("No features have been identifed and selected yet",...
                    "No .mat file") 
                app.prevFreqPeaks.freqPeaks = [];
                app.UITablesFrequencyModes.Data = [-1,-1];
            end
                        
            TestTypeButtonGroupSelectionChanged(app,[]);
            PopulateMLDropDownOptions(app);
            ResetMLTestPlots(app);
            PopulateFrequencyModesTable(app);
            PopulateReTrainMLTable(app);
            TrainingDataAvailableButtonGroupSelectionChanged(app,[]);
            app.NumberOfFeatures.Text = string(length(app.prevFreqPeaks.freqPeaks));
            CheckRequiredToolbox(app);
            UpdateStatus(app,0,append('Application Folder: ',app.applicationPath));
        end

        % Button pushed function: StopButtonFeatExtract, 
        % StopButtonMLTest, StopButtonTestData
        function StopButtonPushed(app, event)
            % This function breaks the code out of any loops and stops the
            % test
            app.stopPressed = 1;
        end

        % Button pushed function: BrowseButtonFile
        function BrowseButtonFileMLPushed(app, event)
            % This function browses the PC to find a simulated signal
            % file to use
            try
                [file,path] = uigetfile('*.csv');
                app.SelectedTestFileLabel.Text = file;
                app.CSVFilePath = fullfile(path,file);
                set(app.StartButtonMLTest,'Enable','on')
            catch
                errordlg("A sample signal .csv file must be selected",...
                    "Invalid selection")
            end
        end

        % Selection changed function: TestTypeButtonGroup
        function TestTypeButtonGroupSelectionChanged(app, event)
            % The function controls if the user can select a simulated 
            % data file
            selectedButton = app.TestTypeButtonGroup.SelectedObject.Text;
            
            if selectedButton == "Live"
                set(app.BrowseButtonFile,'Enable','off')
                set(app.SelectedTestFileLabel,'Enable','off')
                set(app.StartButtonMLTest,'Enable','on')
                
            elseif selectedButton == "Simulated"
                set(app.BrowseButtonFile,'Enable','on')
                set(app.SelectedTestFileLabel,'Enable','on')
                
                if contains(app.SelectedTestFileLabel.Text,".csv")
                    set(app.StartButtonMLTest,'Enable','on')
                else
                    set(app.StartButtonMLTest,'Enable','off')
                end 
            end            
        end

        % Value changed function: MLModelDropDown
        function MLModelDropDownValueChanged(app, event)
            % This function updates the information for the selected model
            selectedModel = app.MLModelDropDown.Value;
            selectedModelFileName = append('model-',selectedModel);
            modelFileInfo = dir(fullfile(app.applicationPath,...
                append(selectedModelFileName,'.mat')));
            
            app.MLmodel = load(selectedModelFileName);
            app.TypeLabel_2.Text = app.MLmodel.trainingData;
            app.AccuracyLabel_2.Text = string(app.MLmodel.mdlAcc);
            app.TrainedLabel_2.Text = extractBefore(modelFileInfo.date,' ');
        end

        % Button pushed function: StartButtonMLTest
        function StartButtonMLTestPushed(app, event)
            % Begin the pass fail test
            set(app.StartButtonMLTest,'Enable','off')
            set(app.BrowseButtonFile,'Enable','off')
            set(app.MLModelDropDown,'Enable','off')
            set(app.TestTypeButtonGroup,'Enable','off')
            set(app.StopButtonMLTest,'Enable','on');
            
            testType = app.TestTypeButtonGroup.SelectedObject.Text;
            holdOn = app.HoldOnPlotsSwitch.Value;
            ResetMLTestPlots(app);
            app.PredictedClass.Text = "UD/D";
            app.ProbabilityBladeCondition.Text = ...
                ['Probability it is undamaged'];
            app.PredictedBladeCondition.Color = 'white';
            
            UpdateStatus(app,0,"Testing the Blade");
            
            % This section creates the loading bar
            originalButtonText = app.ProgressMLTest.Text;
            app.ProgressMLTest.Text = "Collecting...";
            app.ProgressMLTest.IconAlignment = 'bottom';
            wbar = permute(repmat(app.ProgressMLTest.BackgroundColor,...
                30,1,350),[1,3,2]);
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            app.ProgressMLTest.Icon = wbar;
            % Loading bar created
            
            n = 5;
            for i = 1:n
                % Check for stop button press
                if app.stopPressed == 1
                    break
                end
                
                [dataOut(i),classPred(i),probability(i),status] = ...
                    RunSingleTest(app.MLmodel,testType,app.CSVFilePath);
                
                % Check for errors in the test
                if status.error == 1
                    UpdateStatus(app,1,status.desc);
                    break
                end
                
                UpdateMLTestPlots(app,dataOut(i),holdOn);
                app.PredictedClass.Text = classPred(i);
                
                if app.MLmodel.trainingData == "UD + D"
                    desc = ['The probability that the blade is undamaged: ',...
                        append(string(round(probability(i))),' %')];
                else
                    desc = ['No probability is obtained when using an UD model.',...
                        ' Check the prediction with the other UD models'];
                end
                app.ProbabilityBladeCondition.Text = desc;
              
                if string(classPred(i)) == "UD"
                    app.PredictedBladeCondition.Color = 'green';
                else
                    app.PredictedBladeCondition.Color = 'red';
                end
                
                % This section updates the loading bar
                currentProg = min(round((size(wbar,2)-2)*(i/n)),size(wbar,2)-2);
                app.ProgressMLTest.Text = "Impact " + num2str(i) +...
                    " of " + num2str(n);
                app.ProgressMLTest.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.ProgressMLTest.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.ProgressMLTest.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.25);
                % Loading bar updated 
            end
            
            if app.MLmodel.trainingData == "UD + D"      
                if ~(all(classPred == categorical("D")) || all(classPred == categorical("UD")))
                    desc = ['Blade classified as both undamaged and damaged',...
                        ' rerun the test'];
                    app.PredictedClass.Text = "Unknown";
                    app.PredictedBladeCondition.Color = 'white';
                else
                    aveProb = mean(probability);
                    desc = ['Average probability that the blade is undamaged: ',...
                        append(string(round(aveProb)),' %')];
                end
                app.ProbabilityBladeCondition.Text = desc;

            end
            
            
            hold(app.UIAxesRaw_Main,"off")
            hold(app.UIAxesFFTAmp,"off")
            
            % Reset the buttons
            app.ProgressMLTest.Icon = '';
            app.ProgressMLTest.Text = originalButtonText;
            set(app.StopButtonMLTest,'Enable','off');
            set(app.StartButtonMLTest,'Enable','on');
            set(app.BrowseButtonFile,'Enable','on')
            set(app.TestTypeButtonGroup,'Enable','on')
            set(app.MLModelDropDown,'Enable','on')
            
            if status.error == 0
                if app.stopPressed == 0
                    UpdateStatus(app,0,"Test Complete");
                else
                    UpdateStatus(app,0,"Test Stopped");
                    app.stopPressed = 0;
                end
            end
        end

        % Button pushed function: BrowseButtonFolderTestData
        function BrowseButtonFolderTestDataPushed(app, event)
            % This function browses the PC to select a save folder path
            try
                app.trainingDataFolderPath = uigetdir();
                app.SelectedFolderPathLabel.Text = app.trainingDataFolderPath;
            catch
                errordlg("A folder location must be selected",...
                    "Invalid selection")
            end
            
            if contains(app.SelectedFolderPathLabel.Text,"Selected Folder Path")
                set(app.StartButtonTestData,'Enable','off')
            else
                set(app.StartButtonTestData,'Enable','on')
            end 
        end

        % Button pushed function: StartButtonTestData
        function StartButtonTestDataPushed(app, event)
            % This function begins the collect training data method
            set(app.StartButtonTestData,'Enable','off')
            set(app.StopButtonTestData,'Enable','on');
            set(app.BrowseButtonFolderTestData,'Enable','off');
            set(app.FileNameEditField,'Enable','off');
            set(app.NumberofTestsSpinner,'Enable','off');

            UpdateStatus(app,0,"Collecting Impact Test Data");
                        
            % This section creates the loading bar
            originalButtonText = app.ProgressTestData.Text;
            app.ProgressTestData.Text = "Collecting...";
            app.ProgressTestData.IconAlignment = 'bottom';
            wbar = permute(repmat(app.ProgressTestData.BackgroundColor,...
                30,1,350),[1,3,2]);
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            app.ProgressTestData.Icon = wbar;
            % Loading bar created
            
            [highestIdx,bladeFolderPath] = CheckHistoricalBladeTests(...
                app.trainingDataFolderPath,app.FileNameEditField.Value);

            n = app.NumberofTestsSpinner.Value;
            for i = 1:n
                if app.stopPressed == 1
                    break
                end
                
                % Do the impact event once
                idx = highestIdx + i;
                [dataOut,status] = ImpactTest("Yes",idx,bladeFolderPath,...
                    app.FileNameEditField.Value);
                
                % Check for errors in the test
                if status.error == 1
                    UpdateStatus(app,1,status.desc);
                    break
                end
                
                plot(app.UIAxesFeatExtract,dataOut.Time,dataOut.ChannelA)
                xlim(app.UIAxesFeatExtract,[0 inf])
                
                % This section updates the loading bar
                currentProg = min(round((size(wbar,2)-2)*(i/n)),size(wbar,2)-2);
                app.ProgressTestData.Text = "Test " + num2str(i) +...
                    " of " + num2str(n);
                app.ProgressTestData.Icon(2:end-1, 2:currentProg+1, 1) = 0.25391;
                app.ProgressTestData.Icon(2:end-1, 2:currentProg+1, 2) = 0.41016;
                app.ProgressTestData.Icon(2:end-1, 2:currentProg+1, 3) = 0.87891;
                pause(0.25);
                % Loading bar updated                
            end
            
            % Reset the buttons
            app.ProgressTestData.Icon = '';
            app.ProgressTestData.Text = originalButtonText;
            set(app.StopButtonTestData,'Enable','off');
            set(app.StartButtonTestData,'Enable','on');
            set(app.BrowseButtonFolderTestData,'Enable','on');
            set(app.FileNameEditField,'Enable','on');
            set(app.NumberofTestsSpinner,'Enable','on');
            
            if app.stopPressed == 0
                UpdateStatus(app,0,"Test Data Collected");
            else
                UpdateStatus(app,0,"Test Stopped");
                app.stopPressed = 0;
            end
        end

        % Button pushed function: BrowseButtonFolderReML
        function BrowseButtonFolderFeatExtractPushed(app, event)
            % This function browses the PC to select a save folder path
            try
                app.trainingDataFolderPath = uigetdir();
                app.SelectedFolderPathLabelFeatExtract.Text = app.trainingDataFolderPath;
            catch
                errordlg("A folder location must be selected",...
                    "Invalid selection")
            end
            
            if ~contains(app.SelectedFolderPathLabelFeatExtract.Text,...
                    "Selected Folder Path")
                set(app.StartButtonFeatExtract,'Enable','on')
                set(app.BladeConditionSwitchFeatExtract,'Enable','on');
                set(app.NextFileButtonFeatExtract,'Enable','on')
            end 
        end

        % Button pushed function: StartButtonFeatExtract
        function StartButtonReTrainPeaksPushed(app, event)
            % This function begins the feature selection process
            set(app.StartButtonFeatExtract,'Enable','off');
            set(app.NextFileButtonFeatExtract,'Enable','off');
            set(app.BladeConditionSwitchFeatExtract,'Enable','off');
            set(app.StopButtonFeatExtract,'Enable','on');
            set(app.DeleteRowButtonFeatExtract,'Enable','on');
            UpdateStatus(app,0,"Collecting features");
            
            try
                app.prevFreqPeaks = load(fullfile(app.applicationPath,...
                    'freqPeaksLoc'));
            catch
                % No .mat file has been created
                app.prevFreqPeaks.freqPeaks = [];
            end
            
            if contains(app.SelectedFeatExtract.Text,".csv")
                % Select current file and plot it with .mat file peaks 
                UpdateFeatExtractPlots(app);
            else
                % Select new file and plot it with .mat file peaks 
                NextFileButtonPushed(app);
            end
            
            changes = 0;
            while app.stopPressed == 0
                try
                dataTip = findall(app.UIAxesFeatExtract,'Type','hggroup');
                pause(0.2)
                if isempty(dataTip)
                    % No data tip has been selected
                else
                    try
                        pause(0.2) % Important, without it Matlab crashes
                        coords = dataTip.Position;
                    catch
                        pause(1) % Important, without it Matlab crashes
                        coords = dataTip.Position;
                    end
                    newFreq = coords(1);
                    app.prevFreqPeaks.freqPeaks = sort(cat(2,newFreq,...
                        app.prevFreqPeaks.freqPeaks),"ascend");
                    
                    % Update the table with the new frequency
                    PopulateFrequencyModesTable(app);
                    
                    % Update the graph with the new frequency
                    delete(dataTip);
                    xline(app.UIAxesFeatExtract,newFreq,'--r');
                    changes = changes + 1;
                end
                catch
                    % error on findall function
                end
            end
            
            if changes > 0
                f = uifigure;
                msg = ['There are new values, do you want to save the changes? ',...
                    'To use the new features selected, a new machine ',...
                    'learning model will need to be trained'];
                title = 'Confirm Save';
                selection = uiconfirm(f,msg,title,...
                           'Options',{'Save','Cancel'},...
                           'DefaultOption',2,'CancelOption',2,...
                           'CloseFcn',@(h,e) close(f));
                
                if selection == "Save"
                    freqPeaks = sort(app.prevFreqPeaks.freqPeaks,"ascend");
                    save(fullfile(app.applicationPath,"freqPeaksLoc"),...
                        'freqPeaks')
                    app.NumberOfFeatures.Text = string(length(freqPeaks));
                else
                    % Load previous file and update plot and table
                    try
                        app.prevFreqPeaks = load(fullfile(app.applicationPath,...
                            'freqPeaksLoc'));
                    catch
                        % No .mat file has been created
                        app.prevFreqPeaks.freqPeaks = [];
                    end
                    UpdateFeatExtractPlots(app)
                    PopulateFrequencyModesTable(app);
                end
            end
            
            app.stopPressed = 0;
            
            UpdateStatus(app,0,"Features Collected");
            set(app.StartButtonFeatExtract,'Enable','on');
            set(app.NextFileButtonFeatExtract,'Enable','on');
            set(app.BladeConditionSwitchFeatExtract,'Enable','on');
            set(app.StopButtonFeatExtract,'Enable','off');
        end

        % Callback function: BladeConditionSwitchFeatExtract, 
        % NextFileButtonFeatExtract
        function NextFileButtonPushed(app, event)
            % This function choses a random file from the selected folder
            bladeCondition = app.BladeConditionSwitchFeatExtract.Value;
            fileList = dir(fullfile(app.trainingDataFolderPath,bladeCondition,'**/*.csv'));
            fileIdx = randi(length(fileList));
            fileName = fileList(fileIdx).name;
            filePath = fileList(fileIdx).folder;
            app.SelectedFeatExtract.Text = fileName;
            app.CSVFilePath = fullfile(filePath,fileName);
            
            UpdateFeatExtractPlots(app);
            set(app.DeleteRowButtonFeatExtract,'Enable','on')
        end

        % Button pushed function: DeleteRowButtonFeatExtract
        function DeleteRowButtonFeatExtractPushed(app, event)
            % This button deletes a row from the frequency peak table and
            % updates the .mat file and re plots the lines
            f = uifigure;
            msg = ['Do you want to delete the selected peak? To use the '...
               'new features selected, a new machine learning model ',...
               'will need to be trained'];
            title = 'Confirm Delete';
            selection = uiconfirm(f,msg,title,...
                       'Options',{'Delete','Cancel'},...
                       'DefaultOption',2,'CancelOption',2,...
                       'CloseFcn',@(h,e) close(f));
            
            if selection == "Delete"            
                rowNum = app.RowNumberEditFieldFeatExtract.Value;
                try
                    app.UITablesFrequencyModes.Data(rowNum,:) = [];
                    app.UITablesFrequencyModes.Data(:,1) = string(1:...
                        length(app.UITablesFrequencyModes.Data(:,1)));
                catch
                    errordlg("Row number is not valid",...
                        "Invalid selection");
                end
                
                app.prevFreqPeaks.freqPeaks = str2double(app.UITablesFrequencyModes.Data(:,2))';
                freqPeaks = sort(app.prevFreqPeaks.freqPeaks,"ascend");
                save(fullfile(app.applicationPath,"freqPeaksLoc"),...
                    'freqPeaks')
                app.NumberOfFeatures.Text = string(length(freqPeaks));
                
                PopulateFrequencyModesTable(app);
                UpdateFeatExtractPlots(app);
            end
        end

        % Value changed function: 
        % FrequencyMaxkHzSpinnerFeatExtract, 
        % FrequencyMinkHzSpinnerFeatExtract
        function FrequencyMaxkHzSpinnerFeatExtractValueChanged(app, event)
            % This function changes the x axis of the Feature Extraction
            % plot when the value is changed
            minValue = app.FrequencyMinkHzSpinnerFeatExtract.Value;
            maxValue = app.FrequencyMaxkHzSpinnerFeatExtract.Value;
            xlim(app.UIAxesFeatExtract,[minValue maxValue]);
        end

        % Button pushed function: BrowseButtonFolderReTrainML
        function BrowseButtonFolderReTrainMLPushed(app, event)
            % This function browses the PC to select a save folder path
            try
                app.trainingDataFolderPath = uigetdir();
                app.SelectedFolderPathLabelReTrainML.Text = app.trainingDataFolderPath;
            catch
                errordlg("A folder location must be selected",...
                    "Invalid selection")
            end
            
            if ~contains(app.SelectedFolderPathLabelReTrainML.Text,...
                    "Selected Folder Path")
                set(app.StartButtonReTrainML,'Enable','on')
            end
        end

        % Selection changed function: 
        % TrainingDataAvailableButtonGroup
        function TrainingDataAvailableButtonGroupSelectionChanged(app, event)
            % This function updates the possible ML models to train 
            % depending on the training data
            trainingDataType = app.TrainingDataAvailableButtonGroup.SelectedObject.Text;
            
            if trainingDataType == "Undamaged"
                app.MachineLearningMethodtoTrainListBox.Items = ...
                    {'Basic','Gaussian','DBSCAN'};
                set(app.AutoReduceFeaturesButtonGroup,'Enable','off');
            else
                app.MachineLearningMethodtoTrainListBox.Items = ...
                    {'AUTO','Tree','kNN','NB','DA','ShallowNN','DL'};
                set(app.AutoReduceFeaturesButtonGroup,'Enable','on');
            end
        end

        % Button pushed function: StartButtonReTrainML
        function StartButtonReTrainMLPushed(app, event)
            % This function calls the start training functions
            if app.NumberOfFeatures.Text == "0"
                UpdateStatus(app,1,['No peaks have been extracted yet, '...
                    'use the Manual Feature Extraction tab']);
                return
            end
                        
            set(app.StartButtonReTrainML,'Enable','off');
            set(app.BrowseButtonFolderReTrainML,'Enable','off');
            set(app.TrainingDataAvailableButtonGroup,'Enable','off');
            set(app.AutoReduceFeaturesButtonGroup,'Enable','off');
            
            f = uifigure;
            msg = ['Training of the models cannot be stopped after they',...
                ' have begun. DL and AUTO can take a very long time to train',...
                '. The application will need to be restarted if the training',...
                ' is manually tried to be stopped.',...
                ' The progress pop up takes a number of seconds to load'];
            title = 'Begin Training?';
            selection = uiconfirm(f,msg,title,...
                       'Options',{'Yes','Cancel'},...
                       'DefaultOption',2,'CancelOption',2,...
                       'CloseFcn',@(h,e) close(f));
            
            if selection == "Yes"
                UpdateStatus(app,0,"Training begun - monitor progress in pop up");
                            
                [mdlAcc,mdlLoss,classTrue,classPred,~,status] = ...
                    CreateTrainMLAlgorithm(...
                    app.MachineLearningMethodtoTrainListBox.Value,...
                    app.TrainingDataAvailableButtonGroup.SelectedObject.Text,...
                    app.AutoReduceFeaturesButtonGroup.SelectedObject.Text,...
                    app.prevFreqPeaks.freqPeaks,...
                    app.trainingDataFolderPath,...
                    app.applicationPath);
    
                % Check for errors in the test
                if status.error == 1
                    UpdateStatus(app,1,status.desc);
                else
                    app.AccuracyOfTheModel.Text = append(string(mdlAcc),' %');
                    app.LossOfTheModel.Text = append(string(mdlLoss),' %');
                    if ~isempty(classTrue)
                        confusionchart(app.ConfusionChartPanel,classTrue,classPred);
                    else
                        delete(app.ConfusionChartPanel.Children)
                    end
                
                    PopulateReTrainMLTable(app)
                    PopulateMLDropDownOptions(app);
                    TrainingDataAvailableButtonGroupSelectionChanged(app,[]);
                    UpdateStatus(app,0,"Training Completed");
                end
            end
            
            set(app.StartButtonReTrainML,'Enable','on');
            set(app.BrowseButtonFolderReTrainML,'Enable','on');
            set(app.TrainingDataAvailableButtonGroup,'Enable','on');
            set(app.AutoReduceFeaturesButtonGroup,'Enable','on');
        end

        % Button pushed function: DeleteRowButtonReTrainML
        function DeleteRowButtonReTrainMLPushed(app, event)
            % This function deletes the selected model
            f = uifigure;
            msg = ['Selecting yes will delete the select model.',...
               ' The model will need to be trained again to use it'];
            title = 'Delete Model?';
            selection = uiconfirm(f,msg,title,...
                       'Options',{'Yes','Cancel'},...
                       'DefaultOption',2,'CancelOption',2,...
                       'CloseFcn',@(h,e) close(f));
            
            if selection == "Yes"
                modelToDel = app.ModeltoDeleteDropDown.Value;
                modelFullFileName = append('model-',modelToDel,'.mat');
                delete(fullfile(app.applicationPath,modelFullFileName));
                PopulateReTrainMLTable(app);
                PopulateMLDropDownOptions(app);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainPassFailApplicationTab = uitab(app.TabGroup);
            app.MainPassFailApplicationTab.Title = 'Main Pass/Fail Application';
            app.MachineLearningSetupPanel = uipanel(app.MainPassFailApplicationTab);
            app.MachineLearningSetupPanel.Title = 'Machine Learning Setup';
            app.ModelDropDownLabel = uilabel(app.MachineLearningSetupPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.MLModelDropDown = uidropdown(app.MachineLearningSetupPanel);
            app.MLModelDropDown.Items = {'None Trained'};
            app.MLModelDropDown.ValueChangedFcn = createCallbackFcn(app, @MLModelDropDownValueChanged, true);
            app.MLModelDropDown.Value = 'None Trained';
            app.TrainedLabel = uilabel(app.MachineLearningSetupPanel);
            app.TrainedLabel.Text = 'Trained:';
            app.TypeLabel = uilabel(app.MachineLearningSetupPanel);
            app.TypeLabel.Text = 'Type:';
            app.AccuracyLabel = uilabel(app.MachineLearningSetupPanel);
            app.AccuracyLabel.Text = 'Accuracy:';
            app.TypeLabel_2 = uilabel(app.MachineLearningSetupPanel);
            app.TypeLabel_2.Text = 'Type';
            app.AccuracyLabel_2 = uilabel(app.MachineLearningSetupPanel);
            app.AccuracyLabel_2.Text = 'Accuracy';
            app.TrainedLabel_2 = uilabel(app.MachineLearningSetupPanel);
            app.TrainedLabel_2.Text = 'Trained';
            app.ResultsPanel = uipanel(app.MainPassFailApplicationTab);
            app.ResultsPanel.Title = 'Results:';
            app.PredictedBladeCondition = uilamp(app.ResultsPanel);
            app.BladeConditionLabel = uilabel(app.ResultsPanel);
            app.BladeConditionLabel.Text = 'Blade Condition:';
            app.PredictedClass = uilabel(app.ResultsPanel);
            app.PredictedClass.Text = 'UD/D';
            app.ProbabilityBladeCondition = uilabel(app.ResultsPanel);
            app.ProbabilityBladeCondition.Text = 'Probability it is undamaged';
            app.TestSetupPanel = uipanel(app.MainPassFailApplicationTab);
            app.TestSetupPanel.Title = 'Test Setup';
            app.TestTypeButtonGroup = uibuttongroup(app.TestSetupPanel);
            app.TestTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TestTypeButtonGroupSelectionChanged, true);
            app.TestTypeButtonGroup.Title = 'Test Type';
            app.LiveButton = uiradiobutton(app.TestTypeButtonGroup);
            app.LiveButton.Text = 'Live';
            app.LiveButton.Value = true;
            app.SimulatedButton = uiradiobutton(app.TestTypeButtonGroup);
            app.SimulatedButton.Text = 'Simulated';
            app.BrowseButtonFile = uibutton(app.TestSetupPanel, 'push');
            app.BrowseButtonFile.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonFileMLPushed, true);
            app.BrowseButtonFile.Text = 'Browse';
            app.TheSimulatedTestFileLabel = uilabel(app.TestSetupPanel);
            app.TheSimulatedTestFileLabel.Text = 'The Simulated Test File:';
            app.SelectedTestFileLabel = uilabel(app.TestSetupPanel);
            app.SelectedTestFileLabel.Text = 'Selected Test File';
            app.StartButtonMLTest = uibutton(app.MainPassFailApplicationTab, 'push');
            app.StartButtonMLTest.ButtonPushedFcn = createCallbackFcn(app, @StartButtonMLTestPushed, true);
            app.StartButtonMLTest.Text = 'Start';
            app.PlotOptionsPanel = uipanel(app.MainPassFailApplicationTab);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.HoldOnPlotsSwitchLabel = uilabel(app.PlotOptionsPanel);
            app.HoldOnPlotsSwitchLabel.Text = 'Hold On Plots';
            app.HoldOnPlotsSwitch = uiswitch(app.PlotOptionsPanel, 'slider');
            app.HoldOnPlotsSwitch.Items = {'No', 'Yes'};
            app.HoldOnPlotsSwitch.Value = 'No';
            app.FrequencyMaxkHzSpinnerLabel = uilabel(app.PlotOptionsPanel);
            app.FrequencyMaxkHzSpinnerLabel.Text = 'Frequency Max (kHz)';
            app.FrequencyMaxkHzSpinner = uispinner(app.PlotOptionsPanel);
            app.FrequencyMaxkHzSpinner.Limits = [0 Inf];
            app.FrequencyMaxkHzSpinner.Value = 50;
            app.StopButtonMLTest = uibutton(app.MainPassFailApplicationTab, 'push');
            app.StopButtonMLTest.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButtonMLTest.Text = 'Stop';
            app.ProgressMLTest = uibutton(app.MainPassFailApplicationTab, 'push');
            app.ProgressMLTest.Text = 'Machine Learning Test Progress';
            app.UIAxesRaw_Main = uiaxes(app.MainPassFailApplicationTab);
            title(app.UIAxesRaw_Main, 'Raw Signal Data')
            xlabel(app.UIAxesRaw_Main, 'Time (ms)')
            ylabel(app.UIAxesRaw_Main, 'Signal Amplitude (mV)')
            zlabel(app.UIAxesRaw_Main, 'Z')
            app.UIAxesFFTAmp = uiaxes(app.MainPassFailApplicationTab);
            title(app.UIAxesFFTAmp, 'Power Spectrum Amplitude')
            xlabel(app.UIAxesFFTAmp, 'Frequency (kHz)')
            ylabel(app.UIAxesFFTAmp, 'Amplitude')
            zlabel(app.UIAxesFFTAmp, 'Z')
            app.CollectMoreTrainingDataTab = uitab(app.TabGroup);
            app.CollectMoreTrainingDataTab.Title = 'Collect More Training Data';
            app.TestSetupPanel_2 = uipanel(app.CollectMoreTrainingDataTab);
            app.TestSetupPanel_2.Title = 'Test Setup';
            app.BrowseButtonFolderTestData = uibutton(app.TestSetupPanel_2, 'push');
            app.BrowseButtonFolderTestData.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonFolderTestDataPushed, true);
            app.BrowseButtonFolderTestData.Text = 'Browse';
            app.TheBladesTopLevelFolderPathLabel_3 = uilabel(app.TestSetupPanel_2);
            app.TheBladesTopLevelFolderPathLabel_3.Text = 'The Blades Top Level Folder Path:';
            app.SelectedFolderPathLabel = uilabel(app.TestSetupPanel_2);
            app.SelectedFolderPathLabel.Text = 'Selected Folder Path';
            app.FileNameEditFieldLabel = uilabel(app.TestSetupPanel_2);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.TestSetupPanel_2, 'text');
            app.FileNameEditField.Value = 'SN12345_UD';
            app.NumberofTestsSpinnerLabel = uilabel(app.TestSetupPanel_2);
            app.NumberofTestsSpinnerLabel.Text = 'Number of Tests';
            app.NumberofTestsSpinner = uispinner(app.TestSetupPanel_2);
            app.NumberofTestsSpinner.Limits = [1 Inf];
            app.NumberofTestsSpinner.Value = 1;
            app.StartButtonTestData = uibutton(app.CollectMoreTrainingDataTab, 'push');
            app.StartButtonTestData.ButtonPushedFcn = createCallbackFcn(app, @StartButtonTestDataPushed, true);
            app.StartButtonTestData.Text = 'Start';
            app.StopButtonTestData = uibutton(app.CollectMoreTrainingDataTab, 'push');
            app.StopButtonTestData.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButtonTestData.Text = 'Stop';
            app.ProgressTestData = uibutton(app.CollectMoreTrainingDataTab, 'push');
            app.ProgressTestData.Text = 'Training Data Collection Progress';
            app.UIAxesRaw_Col = uiaxes(app.CollectMoreTrainingDataTab);
            title(app.UIAxesRaw_Col, 'Raw Signal Data')
            xlabel(app.UIAxesRaw_Col, 'Time (ms)')
            ylabel(app.UIAxesRaw_Col, 'Signal Amplitude (mV)')
            zlabel(app.UIAxesRaw_Col, 'Z')
            app.ManualFeatureExtractionTab = uitab(app.TabGroup);
            app.ManualFeatureExtractionTab.Title = 'Manual Feature Extraction';
            app.StartButtonFeatExtract = uibutton(app.ManualFeatureExtractionTab, 'push');
            app.StartButtonFeatExtract.ButtonPushedFcn = createCallbackFcn(app, @StartButtonReTrainPeaksPushed, true);
            app.StartButtonFeatExtract.Text = 'Begin Peak Selection';
            app.StopButtonFeatExtract = uibutton(app.ManualFeatureExtractionTab, 'push');
            app.StopButtonFeatExtract.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButtonFeatExtract.Text = 'Stop Peak Selection';
            app.NextFileButtonFeatExtract = uibutton(app.ManualFeatureExtractionTab, 'push');
            app.NextFileButtonFeatExtract.ButtonPushedFcn = createCallbackFcn(app, @NextFileButtonPushed, true);
            app.NextFileButtonFeatExtract.Text = 'Try Next File';
            app.RandomFileLabelFeatExtract = uilabel(app.ManualFeatureExtractionTab);
            app.RandomFileLabelFeatExtract.Text = 'Random File:';
            app.SelectedFeatExtract = uilabel(app.ManualFeatureExtractionTab);
            app.SelectedFeatExtract.Text = 'Selected File';
            app.DeleteRowButtonFeatExtract = uibutton(app.ManualFeatureExtractionTab, 'push');
            app.DeleteRowButtonFeatExtract.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonFeatExtractPushed, true);
            app.DeleteRowButtonFeatExtract.Text = 'Delete';
            app.ManualFeatureExtractionSetupPanel = uipanel(app.ManualFeatureExtractionTab);
            app.ManualFeatureExtractionSetupPanel.Title = 'Manual Feature Extraction Setup';
            app.BrowseButtonFolderReML = uibutton(app.ManualFeatureExtractionSetupPanel, 'push');
            app.BrowseButtonFolderReML.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonFolderFeatExtractPushed, true);
            app.BrowseButtonFolderReML.Text = 'Browse';
            app.TheBladesTopLevelFolderPathLabel = uilabel(app.ManualFeatureExtractionSetupPanel);
            app.TheBladesTopLevelFolderPathLabel.Text = 'The Blades Top Level Folder Path:';
            app.SelectedFolderPathLabelFeatExtract = uilabel(app.ManualFeatureExtractionSetupPanel);
            app.SelectedFolderPathLabelFeatExtract.Text = 'Selected Folder Path';
            app.ExtractedfeaturesmatchthemachinelearningalgorithmLabel = uilabel(app.ManualFeatureExtractionSetupPanel);
            app.ExtractedfeaturesmatchthemachinelearningalgorithmLabel.Text = 'Manual feature extraction is ONLY required if the machine learning method being used, is not deep learning';
            app.FrequencyMaxkHzSpinner_2Label = uilabel(app.ManualFeatureExtractionSetupPanel);
            app.FrequencyMaxkHzSpinner_2Label.Text = 'Frequency Max (kHz)';
            app.FrequencyMaxkHzSpinnerFeatExtract = uispinner(app.ManualFeatureExtractionSetupPanel);
            app.FrequencyMaxkHzSpinnerFeatExtract.Limits = [0 Inf];
            app.FrequencyMaxkHzSpinnerFeatExtract.ValueChangedFcn = createCallbackFcn(app, @FrequencyMaxkHzSpinnerFeatExtractValueChanged, true);
            app.FrequencyMaxkHzSpinnerFeatExtract.Value = 50;
            app.FrequencyMinkHzLabel = uilabel(app.ManualFeatureExtractionSetupPanel);
            app.FrequencyMinkHzLabel.Text = 'Frequency Min (kHz)';
            app.FrequencyMinkHzSpinnerFeatExtract = uispinner(app.ManualFeatureExtractionSetupPanel);
            app.FrequencyMinkHzSpinnerFeatExtract.Limits = [0 Inf];
            app.FrequencyMinkHzSpinnerFeatExtract.ValueChangedFcn = createCallbackFcn(app, @FrequencyMaxkHzSpinnerFeatExtractValueChanged, true);
            app.UITablesFrequencyModes = uitable(app.ManualFeatureExtractionTab);
            app.UITablesFrequencyModes.ColumnName = {'Mode'; 'Frequency (kHz)'};
            app.UITablesFrequencyModes.RowName = {};
            app.UITablesFrequencyModes.ColumnEditable = [false false];
            app.RownumberEditFieldLabel = uilabel(app.ManualFeatureExtractionTab);
            app.RownumberEditFieldLabel.Text = 'Row number';
            app.RowNumberEditFieldFeatExtract = uieditfield(app.ManualFeatureExtractionTab, 'numeric');
            app.RowNumberEditFieldFeatExtract.Limits = [1 Inf];
            app.RowNumberEditFieldFeatExtract.Value = 1;
            app.ConditionLabelFeatExtract = uilabel(app.ManualFeatureExtractionTab);
            app.ConditionLabelFeatExtract.Text = 'Condition:';
            app.BladeConditionSwitchFeatExtract = uiswitch(app.ManualFeatureExtractionTab, 'slider');
            app.BladeConditionSwitchFeatExtract.Items = {'Undamaged', 'Damaged'};
            app.BladeConditionSwitchFeatExtract.ValueChangedFcn = createCallbackFcn(app, @NextFileButtonPushed, true);
            app.BladeConditionSwitchFeatExtract.Value = 'Undamaged';
            app.UIAxesFeatExtract = uiaxes(app.ManualFeatureExtractionTab);
            title(app.UIAxesFeatExtract, 'Power Spectrum Amplitude')
            xlabel(app.UIAxesFeatExtract, 'Frequency (kHz)')
            ylabel(app.UIAxesFeatExtract, 'Amplitude')
            zlabel(app.UIAxesFeatExtract, 'Z')
            app.RetrainMachineLearningModelTab = uitab(app.TabGroup);
            app.RetrainMachineLearningModelTab.Title = 'Retrain Machine Learning Model';
            app.RetrainMachineLearningModelSetupPanel = uipanel(app.RetrainMachineLearningModelTab);
            app.RetrainMachineLearningModelSetupPanel.Title = 'Retrain Machine Learning Model Setup';
            app.BrowseButtonFolderReTrainML = uibutton(app.RetrainMachineLearningModelSetupPanel, 'push');
            app.BrowseButtonFolderReTrainML.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonFolderReTrainMLPushed, true);
            app.BrowseButtonFolderReTrainML.Text = 'Browse';
            app.TheBladesTopLevelFolderPathLabel_2 = uilabel(app.RetrainMachineLearningModelSetupPanel);
            app.TheBladesTopLevelFolderPathLabel_2.Text = 'The Blades Top Level Folder Path:';
            app.SelectedFolderPathLabelReTrainML = uilabel(app.RetrainMachineLearningModelSetupPanel);
            app.SelectedFolderPathLabelReTrainML.Text = 'Selected Folder Path';
            app.TrainingDataAvailableButtonGroup = uibuttongroup(app.RetrainMachineLearningModelSetupPanel);
            app.TrainingDataAvailableButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrainingDataAvailableButtonGroupSelectionChanged, true);
            app.TrainingDataAvailableButtonGroup.Title = 'Training Data Available?';
            app.UndamagedandDamagedButton = uiradiobutton(app.TrainingDataAvailableButtonGroup);
            app.UndamagedandDamagedButton.Text = 'Undamaged and Damaged';
            app.UndamagedandDamagedButton.Value = true;
            app.UndamagedButton = uiradiobutton(app.TrainingDataAvailableButtonGroup);
            app.UndamagedButton.Text = 'Undamaged';
            app.NumberOfFeaturesText = uilabel(app.RetrainMachineLearningModelSetupPanel);
            app.NumberOfFeaturesText.Text = {'Number of extracted features to use in training:'; ''};
            app.NumberOfFeatures = uilabel(app.RetrainMachineLearningModelSetupPanel);
            app.NumberOfFeatures.Text = 'NA';
            app.MachineLearningMethodtoTrainListBoxLabel = uilabel(app.RetrainMachineLearningModelTab);
            app.MachineLearningMethodtoTrainListBoxLabel.Text = 'Machine Learning Method to Train';
            app.MachineLearningMethodtoTrainListBox = uilistbox(app.RetrainMachineLearningModelTab);
            app.MachineLearningMethodtoTrainListBox.Items = {'Algorithms1', 'Algorithms2', 'Algorithms3', 'Algorithms4', 'Algorithms5'};
            app.MachineLearningMethodtoTrainListBox.Value = 'Algorithms1';
            app.MLmodelTable = uitable(app.RetrainMachineLearningModelTab);
            app.MLmodelTable.ColumnName = {'Model'; 'Trained'; 'Type'; 'Accuracy'};
            app.MLmodelTable.RowName = {};
            app.ConfusionChartPanel = uipanel(app.RetrainMachineLearningModelTab);
            app.ConfusionChartPanel.Title = 'Confusion Chart';
            app.StartButtonReTrainML = uibutton(app.RetrainMachineLearningModelTab, 'push');
            app.StartButtonReTrainML.ButtonPushedFcn = createCallbackFcn(app, @StartButtonReTrainMLPushed, true);
            app.StartButtonReTrainML.Text = 'Begin Training';
            app.CurrentTrainedModelsLabel_2 = uilabel(app.RetrainMachineLearningModelTab);
            app.CurrentTrainedModelsLabel_2.Text = {'The speed of training will be drastically impacted by the hardware the application is running on. A computer with an external GPU will train the models much quicker. '; 'The DL and AUTO models will take the longest time to train, whilst the others should be completed in a matter of minutues.'; ''};
            app.AccuracyofthemodelLabel = uilabel(app.RetrainMachineLearningModelTab);
            app.AccuracyofthemodelLabel.Text = 'Accuracy of the model:';
            app.AccuracyOfTheModel = uilabel(app.RetrainMachineLearningModelTab);
            app.AccuracyOfTheModel.Text = 'NA';
            app.LossofthemodelLabel = uilabel(app.RetrainMachineLearningModelTab);
            app.LossofthemodelLabel.Text = 'Loss of the model:';
            app.LossOfTheModel = uilabel(app.RetrainMachineLearningModelTab);
            app.LossOfTheModel.Text = 'NA';
            app.CurrentTrainedModelsLabel_3 = uilabel(app.RetrainMachineLearningModelTab);
            app.CurrentTrainedModelsLabel_3.Text = {'AUTO - Selects the best method'; 'Tree - Binary classification tree'; 'kNN - k-nearest neighbours'; 'NB - Naive Bayes'; 'DA - Discriminant analysis'; 'ShallowNN - Shallow neural network'; 'DL - Deep learning'; ''};
            app.CurrentTrainedModelsLabel_4 = uilabel(app.RetrainMachineLearningModelTab);
            app.CurrentTrainedModelsLabel_4.Text = {'Basic - Simple std statistical analysis'; 'SpectralCluster - graph-based algorithm'; 'Gaussian - Gaussian mixture model'; 'DBSCAN - Density-based spatial clustering of applications with noise'; '(Auto reduce features is not required because the training time is already very quick)'; ''};
            app.AutoReduceFeaturesButtonGroup = uibuttongroup(app.RetrainMachineLearningModelTab);
            app.AutoReduceFeaturesButtonGroup.Title = 'Auto Reduce Features?';
            app.YesButton = uiradiobutton(app.AutoReduceFeaturesButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uiradiobutton(app.AutoReduceFeaturesButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.Descp = uilabel(app.RetrainMachineLearningModelTab);
            app.Descp.Text = '(May reduce accuracy but will speed up training)';
            app.CurrentTrainedModelsLabel_5 = uilabel(app.RetrainMachineLearningModelTab);
            app.CurrentTrainedModelsLabel_5.Text = {'For undamaged and damaged data the best starting methods is kNN'; 'For undamaged the best starting method is DBSCAN'; ''};
            app.DeleteRowButtonReTrainML = uibutton(app.RetrainMachineLearningModelTab, 'push');
            app.DeleteRowButtonReTrainML.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonReTrainMLPushed, true);
            app.DeleteRowButtonReTrainML.Text = 'Delete';
            app.ModeltoDeleteDropDownLabel = uilabel(app.RetrainMachineLearningModelTab);
            app.ModeltoDeleteDropDownLabel.Text = 'Model to Delete';
            app.ModeltoDeleteDropDown = uidropdown(app.RetrainMachineLearningModelTab);
            app.ModeltoDeleteDropDown.Items = {'None Trained'};
            app.ModeltoDeleteDropDown.Value = 'None Trained';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.ErrorLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pass_Fail_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end